classdef Inpunt_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BezugshheButtonGroup           matlab.ui.container.ButtonGroup
        OberkannteDachButton           matlab.ui.control.ToggleButton
        UnterkannteDachButton          matlab.ui.control.ToggleButton
        LngemEditFieldLabel            matlab.ui.control.Label
        LngemEditField                 matlab.ui.control.NumericEditField
        BreitemEditFieldLabel          matlab.ui.control.Label
        BreitemEditField               matlab.ui.control.NumericEditField
        HhemEditFieldLabel             matlab.ui.control.Label
        HhemEditField                  matlab.ui.control.NumericEditField
        BinderabstandmEditFieldLabel   matlab.ui.control.Label
        BinderabstandmEditField        matlab.ui.control.NumericEditField
        BauteildimensionDropDownLabel  matlab.ui.control.Label
        BauteildimensionDropDown       matlab.ui.control.DropDown
        FlchenlastkNm2Label            matlab.ui.control.Label
        FlchenlastkNm2EditField        matlab.ui.control.NumericEditField
        StartButton                    matlab.ui.control.StateButton
        LasteinwirkungButtonGroup      matlab.ui.container.ButtonGroup
        EckeButton                     matlab.ui.control.ToggleButton
        RandButton                     matlab.ui.control.ToggleButton
        FeldButton                     matlab.ui.control.ToggleButton
        OptionenDropDownLabel          matlab.ui.control.Label
        OptionenDropDown               matlab.ui.control.DropDown
        BeschriftungCheckBox           matlab.ui.control.CheckBox
        BemassungCheckBox              matlab.ui.control.CheckBox
        AngezeigteLayerLabel           matlab.ui.control.Label
    end


    methods (Access = private)

        % Value changed function: BauteildimensionDropDown
        function BauteildimensionDropDownValueChanged(app, event)
            
        end

        % Value changed function: BemassungCheckBox
        function BemassungCheckBoxValueChanged(app, event)
            
            
        end

        % Value changed function: BeschriftungCheckBox
        function BeschriftungCheckBoxValueChanged(app, event)
         
        end

        % Selection changed function: BezugshheButtonGroup
        function BezugshheButtonGroupSelectionChanged(app, event)
            
        end

        % Value changed function: BinderabstandmEditField
        function BinderabstandmEditFieldValueChanged(app, event)
             
        end

        % Value changed function: BreitemEditField
        function BreitemEditFieldValueChanged(app, event)
            
        end

        % Value changed function: FlchenlastkNm2EditField
        function FlchenlastkNm2EditFieldValueChanged(app, event)
            
        end

        % Value changed function: HhemEditField
        function HhemEditFieldValueChanged(app, event)
            
        end

        % Value changed function: LngemEditField
        function LngemEditFieldValueChanged(app, event)
            
        end

        % Value changed function: OptionenDropDown
        function OptionenDropDownValueChanged(app, event)
            valueOption = app.OptionenDropDown.Value;
            switch valueOption
                case 'Print Calculation'
                    
                case 'Print Graph'
                    
                case 'Save all'
                    save
                case 'Close'
                    close all
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            % Wandlung der Buttoneingabe in Verrechenbare Werte
            % Bauteildimension
            valueDim = app.BauteildimensionDropDown.Value;
                     switch valueDim
                         case 'IPE200'
                             Dim=0.2
                         case 'IPE220'
                             Dim=0.22
                         case 'IPE240'
                             Dim=0.24
                         case 'IPE260'
                             Dim=0.26
                     end    
            % Bezugshöhe   
            selectedButton = app.BezugshheButtonGroup.SelectedObject;
                switch selectedButton
                    case app.OberkannteDachButton
                        BedH=1
                    case app.UnterkannteDachButton
                        BedH=0
                end
            
            
            %Binderabstand                    
            a = app.BinderabstandmEditField.Value;       
            %Breite Lagerhalle
            b = app.BreitemEditField.Value;
            % Höhe Lagerhalle
            h = app.HhemEditField.Value;
            % Länge Lagerhalle
            l = app.LngemEditField.Value;
            %Flächenlast
            valueLa = app.FlchenlastkNm2EditField.Value;
            % Binderabstand
            b= app.BinderabstandmEditField.Value
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %layer on off (1 On, 0 Off)
            valueBeschriftung = app.BeschriftungCheckBox.Value;
               if valueBeschriftung==1
       LayerBeschriftung =1
      end
            valueBemassung = app.BemassungCheckBox.Value; 
    if valueBemassung ==1
        LayerBemassung =1
    end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            function Lagerhalle_o_Function (l,b,h,BedH,a,Dim,Flaechenlast)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BezugshheButtonGroup = uibuttongroup(app.UIFigure);
            app.BezugshheButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BezugshheButtonGroupSelectionChanged, true);
            app.BezugshheButtonGroup.Title = 'Bezugshöhe';
            app.OberkannteDachButton = uitogglebutton(app.BezugshheButtonGroup);
            app.OberkannteDachButton.Text = 'Oberkannte Dach';
            app.OberkannteDachButton.Value = true;
            app.UnterkannteDachButton = uitogglebutton(app.BezugshheButtonGroup);
            app.UnterkannteDachButton.Text = 'Unterkannte Dach';
            app.LngemEditFieldLabel = uilabel(app.UIFigure);
            app.LngemEditFieldLabel.Text = 'Länge [m]';
            app.LngemEditField = uieditfield(app.UIFigure, 'numeric');
            app.LngemEditField.ValueChangedFcn = createCallbackFcn(app, @LngemEditFieldValueChanged, true);
            app.LngemEditField.ValueDisplayFormat = '%.3f';
            app.BreitemEditFieldLabel = uilabel(app.UIFigure);
            app.BreitemEditFieldLabel.Text = 'Breite[m]';
            app.BreitemEditField = uieditfield(app.UIFigure, 'numeric');
            app.BreitemEditField.ValueChangedFcn = createCallbackFcn(app, @BreitemEditFieldValueChanged, true);
            app.BreitemEditField.ValueDisplayFormat = '%.3f';
            app.HhemEditFieldLabel = uilabel(app.UIFigure);
            app.HhemEditFieldLabel.Text = 'Höhe [m]';
            app.HhemEditField = uieditfield(app.UIFigure, 'numeric');
            app.HhemEditField.ValueChangedFcn = createCallbackFcn(app, @HhemEditFieldValueChanged, true);
            app.HhemEditField.ValueDisplayFormat = '%.3f';
            app.BinderabstandmEditFieldLabel = uilabel(app.UIFigure);
            app.BinderabstandmEditFieldLabel.Text = 'Binderabstand [m]';
            app.BinderabstandmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinderabstandmEditField.ValueChangedFcn = createCallbackFcn(app, @BinderabstandmEditFieldValueChanged, true);
            app.BinderabstandmEditField.ValueDisplayFormat = '%.3f';
            app.BauteildimensionDropDownLabel = uilabel(app.UIFigure);
            app.BauteildimensionDropDownLabel.Text = 'Bauteildimension';
            app.BauteildimensionDropDown = uidropdown(app.UIFigure);
            app.BauteildimensionDropDown.Items = {'IPE200', 'IPE220', 'IPE240', 'IPE260'};
            app.BauteildimensionDropDown.ValueChangedFcn = createCallbackFcn(app, @BauteildimensionDropDownValueChanged, true);
            app.BauteildimensionDropDown.Value = 'IPE200';
            app.FlchenlastkNm2Label = uilabel(app.UIFigure);
            app.FlchenlastkNm2Label.Text = 'Flächenlast [kN/m^2]';
            app.FlchenlastkNm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.FlchenlastkNm2EditField.ValueChangedFcn = createCallbackFcn(app, @FlchenlastkNm2EditFieldValueChanged, true);
            app.FlchenlastkNm2EditField.ValueDisplayFormat = '%.3f';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.LasteinwirkungButtonGroup = uibuttongroup(app.UIFigure);
            app.LasteinwirkungButtonGroup.Title = 'Lasteinwirkung';
            app.EckeButton = uitogglebutton(app.LasteinwirkungButtonGroup);
            app.EckeButton.Text = 'Ecke';
            app.EckeButton.Value = true;
            app.RandButton = uitogglebutton(app.LasteinwirkungButtonGroup);
            app.RandButton.Text = 'Rand';
            app.FeldButton = uitogglebutton(app.LasteinwirkungButtonGroup);
            app.FeldButton.Text = 'Feld';
            app.OptionenDropDownLabel = uilabel(app.UIFigure);
            app.OptionenDropDownLabel.Text = 'Optionen';
            app.OptionenDropDown = uidropdown(app.UIFigure);
            app.OptionenDropDown.Items = {'Print Calculation', 'Print Graph', 'Save all', 'Close'};
            app.OptionenDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionenDropDownValueChanged, true);
            app.OptionenDropDown.Value = 'Print Calculation';
            app.BeschriftungCheckBox = uicheckbox(app.UIFigure);
            app.BeschriftungCheckBox.ValueChangedFcn = createCallbackFcn(app, @BeschriftungCheckBoxValueChanged, true);
            app.BeschriftungCheckBox.Text = 'Beschriftung';
            app.BemassungCheckBox = uicheckbox(app.UIFigure);
            app.BemassungCheckBox.ValueChangedFcn = createCallbackFcn(app, @BemassungCheckBoxValueChanged, true);
            app.BemassungCheckBox.Text = 'Bemassung';
            app.AngezeigteLayerLabel = uilabel(app.UIFigure);
            app.AngezeigteLayerLabel.Text = 'Angezeigte Layer ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Inpunt_window

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end